classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EBusUIFigure                    matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SchedulerTab                    matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        EnterManuallyTab                matlab.ui.container.Tab
        BusParametersPanel              matlab.ui.container.Panel
        IDEditFieldLabel                matlab.ui.control.Label
        IDEditField                     matlab.ui.control.NumericEditField
        ChargerEfficiencyEditFieldLabel  matlab.ui.control.Label
        ChargerEfficiencyEditField      matlab.ui.control.NumericEditField
        ChargerPowerkWEditField_2Label  matlab.ui.control.Label
        ChargerPowerkWEditField_2       matlab.ui.control.NumericEditField
        AddBusButton                    matlab.ui.control.Button
        Arrival_timeEditField_2Label    matlab.ui.control.Label
        Arrival_timeEditField_2         matlab.ui.control.EditField
        Depart_timeEditFieldLabel       matlab.ui.control.Label
        Depart_timeEditField            matlab.ui.control.EditField
        RemoveLastButton                matlab.ui.control.Button
        SchedulingParametersPanel       matlab.ui.container.Panel
        StepsSliderLabel                matlab.ui.control.Label
        StepsSlider                     matlab.ui.control.Slider
        ScheduleButton                  matlab.ui.control.Button
        Label_2                         matlab.ui.control.Label
        GoalButtonGroup                 matlab.ui.container.ButtonGroup
        MinPowerButton                  matlab.ui.control.RadioButton
        MinChargersButton               matlab.ui.control.RadioButton
        VariationParameterButtonGroup   matlab.ui.container.ButtonGroup
        TimeButton                      matlab.ui.control.RadioButton
        PowerButton                     matlab.ui.control.RadioButton
        MaxPowerkWEditFieldLabel        matlab.ui.control.Label
        MaxPowerkWEditField             matlab.ui.control.EditField
        MaxChargersEditFieldLabel       matlab.ui.control.Label
        MaxChargersEditField            matlab.ui.control.EditField
        UITable_3                       matlab.ui.control.Table
        BatteryParametersPanel_2        matlab.ui.container.Panel
        CapacitykAhEditField_2Label     matlab.ui.control.Label
        CapacitykAhEditField_2          matlab.ui.control.NumericEditField
        TemperatureCEditField_2Label    matlab.ui.control.Label
        TemperatureCEditField_2         matlab.ui.control.NumericEditField
        NrOfSerieCellsEditField_2Label  matlab.ui.control.Label
        NrOfSerieCellsEditField_2       matlab.ui.control.NumericEditField
        CellTypeDropDown_2Label         matlab.ui.control.Label
        CellTypeDropDown_2              matlab.ui.control.DropDown
        NrOfParallelCellsEditField_2Label  matlab.ui.control.Label
        NrOfParallelCellsEditField_2    matlab.ui.control.NumericEditField
        ArrivalSOCEditField_2Label      matlab.ui.control.Label
        ArrivalSOCEditField_2           matlab.ui.control.NumericEditField
        DepartureSOCEditField_2Label    matlab.ui.control.Label
        DepartureSOCEditField_2         matlab.ui.control.NumericEditField
        SaveDataButton_2                matlab.ui.control.Button
        ImportTab                       matlab.ui.container.Tab
        ImportBusListPanel              matlab.ui.container.Panel
        OKButton_2                      matlab.ui.control.Button
        LoadmatfileButton               matlab.ui.control.Button
        UITable_4                       matlab.ui.control.Table
        SchedulingParametersPanel_2     matlab.ui.container.Panel
        StepsSlider_2Label              matlab.ui.control.Label
        StepsSlider_2                   matlab.ui.control.Slider
        ScheduleButton_2                matlab.ui.control.Button
        Label_3                         matlab.ui.control.Label
        GoalButtonGroup_2               matlab.ui.container.ButtonGroup
        MinPowerButton_2                matlab.ui.control.RadioButton
        MinChargersButton_2             matlab.ui.control.RadioButton
        VariationParameterButtonGroup_2  matlab.ui.container.ButtonGroup
        TimeButton_2                    matlab.ui.control.RadioButton
        PowerButton_2                   matlab.ui.control.RadioButton
        MaxPowerkWEditField_2Label      matlab.ui.control.Label
        MaxPowerkWEditField_2           matlab.ui.control.EditField
        MaxChargersEditField_2Label     matlab.ui.control.Label
        MaxChargersEditField_2          matlab.ui.control.EditField
        SaveDataButton                  matlab.ui.control.Button
        SimulatorTab                    matlab.ui.container.Tab
        BatteryParametersPanel          matlab.ui.container.Panel
        CapacitykAhEditFieldLabel       matlab.ui.control.Label
        CapacitykAhEditField            matlab.ui.control.NumericEditField
        TemperatureCEditFieldLabel      matlab.ui.control.Label
        TemperatureCEditField           matlab.ui.control.NumericEditField
        NrOfParallelCellsEditFieldLabel  matlab.ui.control.Label
        NrOfParallelCellsEditField      matlab.ui.control.NumericEditField
        NrOfSerieCellsEditFieldLabel    matlab.ui.control.Label
        NrOfSerieCellsEditField         matlab.ui.control.NumericEditField
        CellTypeDropDownLabel           matlab.ui.control.Label
        CellTypeDropDown                matlab.ui.control.DropDown
        ArrivalSOCEditFieldLabel        matlab.ui.control.Label
        ArrivalSOCEditField             matlab.ui.control.NumericEditField
        DepartureSOCEditFieldLabel      matlab.ui.control.Label
        DepartureSOCEditField           matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
        SimulationParametersPanel       matlab.ui.container.Panel
        StepSliderLabel                 matlab.ui.control.Label
        StepSlider                      matlab.ui.control.Slider
        UnitDropDownLabel               matlab.ui.control.Label
        UnitDropDown                    matlab.ui.control.DropDown
        RunSimulationButton             matlab.ui.control.Button
        ChargerPowerkWEditFieldLabel    matlab.ui.control.Label
        ChargerPowerkWEditField         matlab.ui.control.NumericEditField
        EfficiencyLabel                 matlab.ui.control.Label
        EfficiencyEditField             matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        ExternPlotSwitchLabel           matlab.ui.control.Label
        ExternPlotSwitch                matlab.ui.control.Switch
    end


    properties (Access = private)
         Ri_soc_LookUp = [0    0     10      20      30      40      50      60      70      80      90      100;
                    -10   3.27	1.71	1.70	1.68	1.67	1.66	1.63	1.62	1.60	1.58	1.51;
                    0     2.66	1.39	1.38	1.37	1.35	1.34	1.33	1.31	1.30	1.28	1.22;
                    10    2.32	1.21	1.20	1.19	1.18	1.17	1.16	1.15	1.13	1.11	1.06;
                    25    1.98	1.04	1.03	1.02	1.01	1.00	0.99	0.98	0.97	0.95	0.91;
                    40    1.87	0.98	0.97	0.96	0.95	0.95	0.93	0.93	0.92	0.90	0.86;
                    50    1.80	0.94	0.93	0.93	0.92	0.91	0.90	0.89	0.88	0.87	0.83];
        Imax_LookUp =      [   0	-40	-30	-20	-10	0	10	15	25	30	65	85;
                   0	0	0	0	0	0	0	0	0	0	0	0;
                   10	0	0	0	0	0	0	0	0	0	0	0;
                   20	2	2	2	2	2	2	2	2	2	2	0;
                   30	6	6	6	6	6	6	6	6	6	6	0;
                   40	10	10	10	10	10	10	10	10	10	10	0;
                   50	20	20	20	20	20	20	20	20	20	20	0;
                   60	40	40	40	40	40	40	40	40	40	40	0;
                   70	60	60	60	60	60	60	60	60	60	60	0;
                   80	60	60	60	60	60	60	60	60	60	60	0;
                   90	60	60	60	60	60	60	60	60	60	60	0;
                   100	0	0	0	0	0	0	0	0	0	0	0];
        VoltSoc_LookUp =        [0.000 0.021 0.023 0.028 0.036 0.043 0.050 0.059 0.071 0.083 0.105 0.153 0.229 0.305 0.375 0.494 0.582 0.682 0.761 0.864 0.939 0.986 0.995 1.000;
                 2.005 2.120 2.208 2.301 2.389 2.505 2.588 2.708 2.821 2.936 3.031 3.115 3.164 3.189 3.207 3.229 3.235 3.244 3.260 3.271 3.286 3.306 3.352 3.421];
        TempCorrection_LookUp = [0.34 0.57 0.72 0.84 0.91 1.00 1.02 1.03;
                 -30  -20  -10  0    10   25   35   45  ];
        
        ID =[]%         
        Arrival_SOC  =[] % Description
        Arrival_time = []
        Departure_SOC = [] % Description
        Departure_time =[]
        Temperatur =[]
        NumberOfCellsPll =[]
        NumberOfCellsSerie = []
        Cmax =[]
        Pmax =[]
        Peff =[]
        
        Opt_Goal = 'Pmin'% Description
        Var ='T' % Description
    end

    properties (Access = public)
        %General
        ExternalPlot = 0;
        % Sim Tab
        Sim_Battery              % Description
        Sim_dt                   % Description
        Sim_tUnit                % Description
        SimP                     % Description
        Sim_Pefficiency          % Description
        % Schedule Enter Manually Tab
        Sched_Bus                % Description
        Sched_Bus_Array =[]      % Description
        Sched_dt                 % Description
        Sched_tUnit              % Description
        Sched_P                  % Description
        Sched_Pefficiency        % Description
        Sched_nPmax              % Description
        Sched_Pgesmax            % Description
    end 


    methods (Access = private)
        
        function results = func(app)
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            %Simulate
            app.Sim_Battery=aBattery();
            app.Sim_Battery.Ri_soc_LookUp=app.Ri_soc_LookUp;
            app.Sim_Battery.Imax_LookUp=app.Imax_LookUp;
            app.Sim_Battery.VoltSoc_LookUp=app.VoltSoc_LookUp;
            app.Sim_Battery.TempCorrection_LookUp=app.TempCorrection_LookUp;
            app.Sim_tUnit='s';
            app.Sim_dt=1;
            app.Sim_Battery.Temperatur =25;
            %Schedule
            app.Sched_Bus = aBus();
            app.UITable_3.Data=[];
            app.UITable_4.Data=[];
    
            app.Sched_Bus.ID=0;
            app.Sched_Bus.Battery =aBattery();
            app.Sched_Bus.Battery.Ri_soc_LookUp=app.Ri_soc_LookUp;
            app.Sched_Bus.Battery.Imax_LookUp=app.Imax_LookUp;
            app.Sched_Bus.Battery.VoltSoc_LookUp=app.VoltSoc_LookUp;
            app.Sched_Bus.Battery.TempCorrection_LookUp=app.TempCorrection_LookUp;
            app.Sched_tUnit='s';
            app.Sched_Bus_Array=[];
            app.Label_2.Text =num2str( fix( app.StepsSlider.Value ) );
            app.Label_3.Text =num2str( fix( app.StepsSlider.Value ) );
            
        end

        % Button pushed function: LoadmatfileButton
        function OpenCSV(app, event)
            [file,path,indx] = uigetfile('*.mat');
            if isequal(file,0)
               disp('User selected Cancel')
            else
               disp(['User selected ', fullfile(path, file),... 
                     ' and filter index: ', num2str(indx)])
            end
          
            var = load(fullfile(path, file) );
            app.Sched_Bus_Array =var.BusArray;
            
        end

        % Value changed function: ArrivalSOCEditField
        function Set_SimStartSOC(app, event)
            value = app.ArrivalSOCEditField.Value;
            app.Sim_Battery.SOC=value;
            
        end

        % Value changed function: DepartureSOCEditField
        function Set_SimEndSOC(app, event)
            value = app.DepartureSOCEditField.Value;
            app.Sim_Battery.endSOC=value;
        end

        % Value changed function: CapacitykAhEditField
        function Set_SimCapacity(app, event)
            value = app.CapacitykAhEditField.Value;
            app.Sim_Battery.Cmax = value*3600; % from kAh to As
        end

        % Value changed function: NrOfParallelCellsEditField
        function Set_SimNrPllCells(app, event)
            value = app.NrOfParallelCellsEditField.Value;
            app.Sim_Battery.NumberOfCellsPll=value;
        end

        % Value changed function: NrOfSerieCellsEditField
        function Set_SimNrSerCells(app, event)
            value = app.NrOfSerieCellsEditField.Value;
            app.Sim_Battery.NumberOfCellsSerie=value;
        end

        % Value changed function: CellTypeDropDown
        function Set_SimCellType(app, event)
            value = app.CellTypeDropDown.Value;
            if strcmp(value,'LiPo')   
                %Set the LookUps
                app.Sim_Battery.Ri_soc_LookUp=app.Ri_soc_LookUp;
                app.Sim_Battery.Imax_LookUp=app.Imax_LookUp;
                app.Sim_Battery.VoltSoc_LookUp=app.VoltSoc_LookUp;
                app.Sim_Battery.TempCorrection_LookUp=app.TempCorrection_LookUp;
            
            elseif strcmp(value,'LTO')
                f = msgbox('This Cell Type is not Supported Yet !','Error');
            elseif strcmp(value,'NMC')
                f = msgbox('This Cell Type is not Supported Yet !','Error');
            end
        end

        % Value changed function: StepSlider
        function Set_SimStep(app, event)
            app.Sim_dt   = app.StepSlider.Value;
            
        end

        % Value changed function: UnitDropDown
        function Set_SimUnit(app, event)
            value = app.UnitDropDown.Value;
            if strcmp(value,'Hours')
                app.Sim_tUnit='h';
            elseif strcmp(value,'Minutes')
                app.Sim_tUnit='min';
            elseif strcmp(value,'Seconds')
                app.Sim_tUnit='s';
            end     
        end

        % Value changed function: ChargerPowerkWEditField
        function Set_SimP(app, event)
           app.SimP = app.ChargerPowerkWEditField.Value;
            
        end

        % Value changed function: EfficiencyEditField
        function Set_SimPeff(app, event)
           app.Sim_Pefficiency = app.EfficiencyEditField.Value;
            
        end

        % Button pushed function: RunSimulationButton
        function Run_Simulation(app, event)
            
            %
            dt0 =app.StepSlider.Value;
            p= app.ChargerPowerkWEditField.Value * app.EfficiencyEditField.Value*10;
            value = app.CellTypeDropDown.Value;
            
            if strcmp(value,'LiPo')   
                %Set the LookUps
                app.Sim_Battery.Ri_soc_LookUp=app.Ri_soc_LookUp;
                app.Sim_Battery.Imax_LookUp=app.Imax_LookUp;
                app.Sim_Battery.VoltSoc_LookUp=app.VoltSoc_LookUp;
                app.Sim_Battery.TempCorrection_LookUp=app.TempCorrection_LookUp;
            
            elseif strcmp(value,'LTO')
                f = msgbox('This Cell Type is not Supported Yet !','Error');
            elseif strcmp(value,'NMC')
                f = msgbox('This Cell Type is not Supported Yet !','Error');
            end
            value = app.UnitDropDown.Value;
            if strcmp(value,'Hours')
                app.Sim_tUnit='h';
            elseif strcmp(value,'Minutes')
                app.Sim_tUnit='min';
            elseif strcmp(value,'Seconds')
                app.Sim_tUnit='s';
            end 
            
            if strcmp(app.ExternPlotSwitch.Value,'Off')
                withplot = 0;
            else
                withplot = 1;
            end
            
            
            unit = app.Sim_Battery.ReturnUnit(app.Sim_tUnit);
            un=app.Sim_tUnit;
            
            
            app.Sim_Battery= aBattery();
            app.Sim_Battery.Cmax=app.CapacitykAhEditField.Value * 3600 ;%500*10^3;%As
            app.Sim_Battery.SOC=app.ArrivalSOCEditField.Value;
            app.Sim_Battery.endSOC=app.DepartureSOCEditField.Value;
            app.Sim_Battery.Temperatur=app.TemperatureCEditField.Value;
            app.Sim_Battery.NumberOfCellsPll=app.NrOfParallelCellsEditField.Value;
            app.Sim_Battery.NumberOfCellsSerie=app.NrOfSerieCellsEditField.Value;
            
            app.Sim_Battery.Ri_soc_LookUp=app.Ri_soc_LookUp;
            app.Sim_Battery.Imax_LookUp=app.Imax_LookUp;
            app.Sim_Battery.VoltSoc_LookUp=app.VoltSoc_LookUp;
            app.Sim_Battery.TempCorrection_LookUp=app.TempCorrection_LookUp;
            
            app.Sim_Battery
            
            [C,I,P]=app.Sim_Battery.simulateCharge(dt0 ,p,withplot,app.Sim_tUnit);
            [~,i]=size(C);
            t= (0:dt0:dt0*(i-1));
            t=t/unit;
            dt0=dt0/unit;    
            Pmax=max(abs(P));
            p1 = plot(app.UIAxes,t,-P/1000);%graph type    
            hold(app.UIAxes,'on');
            p2 = plot(app.UIAxes,t,Pmax*ones(size(t))/1000);
            legend(app.UIAxes,[p1 p2],{'Pab','Pmax'})
            title(app.UIAxes,'P (Charger) Plot')
            xlabel(app.UIAxes,strcat('Time in ',{' '},un))
            ylabel(app.UIAxes,'P[kW]')
            xlim(app.UIAxes,[0 dt0*(i-1)])
            ylim(app.UIAxes,[0 Pmax*1.2/1000])
            hold(app.UIAxes,'off');
            refreshdata;
        end

        % Value changed function: TemperatureCEditField
        function Set_SimTemperature(app, event)
            app.Sim_Battery.Temperatur = app.TemperatureCEditField.Value;
            
        end

        % Value changing function: StepSlider
        function Set_SimStepLabel(app, event)
            app.Label.Text =num2str( fix( event.Value ) );
            
        end

        % Value changed function: ExternPlotSwitch
        function Set_ExternPlot(app, event)
            app.ExternalPlot = app.ExternPlotSwitch.Value;
            
        end

        % Value changed function: ArrivalSOCEditField_2
        function Set_BusArrSOC(app, event)
           app.Sched_Bus.Arrival_SOC = app.ArrivalSOCEditField_2.Value;
           app.Sched_Bus.Battery.SOC=app.Sched_Bus.Arrival_SOC;    
        end

        % Value changed function: DepartureSOCEditField_2
        function Set_BusDepSOC(app, event)
            app.Sched_Bus.Departure_SOC = app.DepartureSOCEditField_2.Value;
            app.Sched_Bus.Battery.endSOC=app.Sched_Bus.Departure_SOC;
        end

        % Value changed function: CapacitykAhEditField_2
        function Set_BusCapacity(app, event)
            app.Sched_Bus.Battery.Cmax = app.CapacitykAhEditField_2.Value*3600;           
        end

        % Value changed function: TemperatureCEditField_2
        function Set_BusTemp(app, event)
            app.Sched_Bus.Battery.Temperatur = app.TemperatureCEditField_2.Value;       
        end

        % Value changed function: NrOfParallelCellsEditField_2
        function Set_BusPllCells(app, event)
            app.Sched_Bus.Battery.NumberOfCellsPll = app.NrOfParallelCellsEditField_2.Value;           
        end

        % Value changed function: NrOfSerieCellsEditField_2
        function Set_BusSerCells(app, event)
            app.Sched_Bus.Battery.NumberOfCellsSerie = app.NrOfSerieCellsEditField_2.Value;        
        end

        % Value changed function: CellTypeDropDown_2
        function Set_BusCellType(app, event)
            value = app.CellTypeDropDown_2.Value;
            if strcmp(value,'LiPo')   
                %Set the LookUps
                app.Sched_Bus.Battery.Ri_soc_LookUp=app.Ri_soc_LookUp;
                app.Sched_Bus.Battery.Imax_LookUp=app.Imax_LookUp;
                app.Sched_Bus.Battery.VoltSoc_LookUp=app.VoltSoc_LookUp;
                app.Sched_Bus.Battery.TempCorrection_LookUp=app.TempCorrection_LookUp;
            elseif strcmp(value,'LTO')
                f = msgbox('This Cell Type is not Supported Yet !','Error');
            elseif strcmp(value,'NMC')
                f = msgbox('This Cell Type is not Supported Yet !','Error');
            end
            
        end

        % Value changed function: IDEditField
        function Set_BusID(app, event)
             value= app.IDEditField.Value;
             app.Sched_Bus.ID=value;
        end

        % Value changed function: Depart_timeEditField
        function Set_BusDepTime(app, event)
            value = app.Depart_timeEditField.Value;   
            pattern = '^([0-0][3-5]|[2][0-3]):([0-5][0-9])$';
            match = regexp(value, pattern, 'match');
            time = char(match)
            if time == ""
                f = msgbox('Time should be hh:mm between 03:59 and 05:59 !','Error');
                app.Depart_timeEditField.Value ='05:30'
            else
                app.Sched_Bus.Departure_time=time;
            end
        end

        % Value changed function: ChargerPowerkWEditField_2
        function Set_BusChargerP(app, event)
            app.Sched_Bus.Pmax = app.ChargerPowerkWEditField_2.Value*1000; 
        end

        % Value changed function: ChargerEfficiencyEditField
        function Set_BusChargerPeff(app, event)
            app.Sched_Bus.Peff = app.ChargerEfficiencyEditField.Value;      
        end

        % Callback function
        function Set_BusTimeUnit(app, event)
            value  = app.UnitDropDown_2.Value;
            if strcmp(value,'Hours')
                app.Sched_tUnit='h';
            elseif strcmp(value,'Minutes')
                app.Sched_tUnit='min';
            elseif strcmp(value,'Seconds')
                app.Sched_tUnit='s';
            end
            disp(app.Sched_tUnit)
            
        end

        % Button pushed function: AddBusButton
        function Add_Bus(app, event)

            %ArrSOC
            app.Arrival_SOC = [app.Arrival_SOC;app.ArrivalSOCEditField_2.Value];
            %app.Sched_Bus.Battery.SOC=app.Sched_Bus.Arrival_SOC; 
            %Dep SOC
            app.Departure_SOC = [app.Departure_SOC ;app.DepartureSOCEditField_2.Value];
            %app.Sched_Bus.Battery.endSOC=app.Sched_Bus.Departure_SOC;
            %Capacity
            app.Cmax = [app.Cmax;app.CapacitykAhEditField_2.Value*3600];
            %Tempertature 
            app.Temperatur = [app.Temperatur;app.TemperatureCEditField_2.Value];       
            %Cell PLL
            app.NumberOfCellsPll = [app.NumberOfCellsPll;app.NrOfParallelCellsEditField_2.Value]; 
            %Cell Ser
            app.NumberOfCellsSerie = [app.NumberOfCellsSerie;app.NrOfSerieCellsEditField_2.Value];        
            %Cell Type
            app.Sched_Bus.Battery.Ri_soc_LookUp=app.Ri_soc_LookUp;
            app.Sched_Bus.Battery.Imax_LookUp=app.Imax_LookUp;
            app.Sched_Bus.Battery.VoltSoc_LookUp=app.VoltSoc_LookUp;
            app.Sched_Bus.Battery.TempCorrection_LookUp=app.TempCorrection_LookUp;
            %Bus ID
            app.ID=[app.ID;app.IDEditField.Value];
            %Bus dt
            app.Sched_Bus.dt=app.StepsSlider.Value;
            %ArrTime
            app.Arrival_time   = [app.Arrival_time;{app.Arrival_timeEditField_2.Value}];
            %DepTime 
            app.Departure_time = [app.Departure_time;{app.Depart_timeEditField.Value}];  
            %Pmax 
            app.Pmax = [app.Pmax;app.ChargerPowerkWEditField_2.Value*1000];
            %PEffeciency
            app.Peff = [app.Peff;app.ChargerEfficiencyEditField.Value/100];
            %SchedStep
            app.Sched_dt=num2str( fix( app.StepsSlider.Value ) );
            %SchedStep
            app.Sched_tUnit='s';


            str1 = int2str(app.IDEditField.Value);
            str2 = app.Arrival_timeEditField_2.Value;
            str3 = 0;
            str4 = 0;
            str5 = int2str(app.ChargerPowerkWEditField_2.Value);
            app.UITable_3.Data  = [app.UITable_3.Data;{str1,str2,str3,str4,str5}]; 
            app.UITable_4.Data  = [app.UITable_4.Data;{str1,str2,str3,str4,str5}]; 
           
            
        end

        % Value changed function: StepsSlider, StepsSlider_2
        function Set_SchedStep(app, event)
            value = app.StepsSlider.Value;
            app.Sched_dt=num2str( fix( value ) );      
        end

        % Value changing function: StepsSlider, StepsSlider_2
        function Set_SchedStepLabel(app, event)
            app.Label_2.Text =num2str( fix( event.Value ) );
            app.Label_3.Text =num2str( fix( event.Value ) );
            app.StepsSlider_2.Value = event.Value;
            app.StepsSlider.Value = event.Value;
        end

        % Callback function
        function Set_NmaxChargers(app, event)
            app.Sched_nPmax = app.MaxNumberChargerEditField.Value;
            
        end

        % Callback function
        function Set_Ptmax(app, event)
            app.Sched_Pgesmax = app.PmaxkWEditField.Value;
            
        end

        % Value changed function: Arrival_timeEditField_2
        function Set_BusArrTime(app, event)
            value = app.Arrival_timeEditField_2.Value;
            pattern = '^([0-0][0-3]|[2][0-3]):([0-5][0-9])$';
            match = regexp(value, pattern, 'match');
            time = char(match)
            if time == ""
                f = msgbox('Time should be hh:mm between 00:00 and 03:59 !','Error');
                app.Arrival_timeEditField_2.Value = '00:30'
            else
                app.Sched_Bus.Arrival_time=time;
            end


        end

        % Button pushed function: RemoveLastButton
        function RemoveLast(app, event)
            [sz1,sz2] =size(app.UITable_3.Data);
            sz = max(sz1,sz2);
            if sz>0
                app.UITable_3.Data(end,:)=[];
                app.UITable_4.Data(end,:)=[];
                app.ID(end,:)=[];
                app.Arrival_time(end,:)=[];
                app.Departure_time(end,:)=[];
                app.Arrival_SOC(end,:)=[];
                app.Departure_SOC(end,:)=[];
                app.Cmax(end,:)=[];
                app.Temperatur(end,:)=[];
                app.NumberOfCellsPll(end,:)=[];
                app.NumberOfCellsSerie(end,:)=[];
                app.Pmax(end,:)=[];
                app.Peff(end,:)=[];
            end
        end

        % Button pushed function: ScheduleButton
        function Schedule(app, event)
            arrtime=datetime('00:00','InputFormat','HH:mm');
            deptime=datetime('06:00','InputFormat','HH:mm');
            global dt iteration        
            iteration = 0
            dt =  fix(app.StepsSlider.Value) 
            [~,sz] =size(app.ID');
            sz
            for i=1:sz
                BusArray(i)=aBus();
                BusArray(i).ID=app.ID(i);
                BusArray(i).Arrival_time=app.Arrival_time(i);
                BusArray(i).Departure_time=app.Departure_time(i);
                %---- SOC Variables ----% 
                BusArray(i).Arrival_SOC=app.Arrival_SOC(i);
                BusArray(i).Departure_SOC=app.Departure_SOC(i);
                %---- Scheduling Variables ----% 
                BusArray(i).ChargingStart=0;
                BusArray(i).ChargingTime=0;
                %---- BatteryData ----% 
                BusArray(i).Battery = aBattery();
                %BusArray(i).Battery.ID=BusArray(i).ID;
                BusArray(i).Battery.Cmax=app.Cmax(i);
                BusArray(i).Battery.SOC=BusArray(i).Arrival_SOC;
                BusArray(i).Battery.endSOC=BusArray(i).Departure_SOC;
                BusArray(i).Battery.Temperatur=app.Temperatur(i);
                BusArray(i).Battery.NumberOfCellsPll=app.NumberOfCellsPll(i);
                BusArray(i).Battery.NumberOfCellsSerie=app.NumberOfCellsSerie(i);
                %---- LookUps ----%
                BusArray(i).Battery.Ri_soc_LookUp=app.Ri_soc_LookUp;
                BusArray(i).Battery.Imax_LookUp=app.Imax_LookUp;
                BusArray(i).Battery.VoltSoc_LookUp=app.VoltSoc_LookUp;
                BusArray(i).Battery.TempCorrection_LookUp=app.TempCorrection_LookUp;
                BusArray(i).Pmax = app.Pmax(i);
                BusArray(i).Peff = app.Peff(i);               
            end
          
          
            [~,sz] =size(BusArray);
            for i=1:sz
                BusArray(i).CalcP(dt,BusArray(i).Pmax,0,'s');
                arrBus = datetime(BusArray(i).Arrival_time, 'InputFormat','HH:mm');
                depBus = datetime(BusArray(i).Departure_time, 'InputFormat','HH:mm');
                start_time=abs(etime(datevec(arrtime),datevec(arrBus)));
                BusArray(i).ChargingStart = start_time;
                BusArray(i).Arrival_seconds = start_time;                
                all_time = abs(etime(datevec(arrBus),datevec(depBus)));
                all_time = round(all_time);
                LookUp = app.VoltSoc_LookUp;
                [Energie_stored, Energie_grid] = CalC_Energie(BusArray(i),BusArray(i).Peff,LookUp);
                Pm = (Energie_grid*1000*3600)/(all_time) ;
                BusArray(i).Pmin=Pm; 
            end
            app.Sched_Bus_Array = BusArray;


            if strcmp(app.ExternPlotSwitch.Value,'Off')
                withplot = 0
            else
                withplot = 1
            end
             
            
            tic;
          
            
            if strcmp(app.Opt_Goal,'Pmin') && strcmp(app.Var,'T')
                disp('minimizing P with t')
                [Pmax_ist, Nmax_ist] = minimize_total_Power_with_t0(app.Sched_Bus_Array, str2num(app.MaxPowerkWEditField.Value),arrtime,deptime, withplot);
            elseif strcmp(app.Opt_Goal,'Nmin') && strcmp(app.Var,'T')
                disp('minimizing N with t')
                [Pmax_ist, Nmax_ist] = minimize_nChargers_with_t0(app.Sched_Bus_Array,str2num(app.MaxChargersEditField.Value),arrtime,deptime, withplot);
            elseif strcmp(app.Opt_Goal,'Pmin') && strcmp(app.Var,'P')
                disp('minimizing P with P')
                [Pmax_ist, Nmax_ist] = minimize_total_Power_with_P(app.Sched_Bus_Array,str2num(app.MaxPowerkWEditField.Value),arrtime,deptime, withplot);
            else
                f = msgbox('Reducing Number Of Chargers with P variation not possible !','Error');
            end
            toc
            

            for i=1:sz
                start = seconds(BusArray(i).ChargingStart);
                start.Format = 'hh:mm';
                finish = seconds(BusArray(i).ChargingStart+BusArray(i).ChargingTime);
                finish.Format ='hh:mm';
                P = BusArray(i).Pmax/1000 ;
                app.UITable_3.Data(i,1) = {int2str(BusArray(i).ID)};
                app.UITable_3.Data(i,2) = {datestr(BusArray(i).Arrival_time)};
                app.UITable_3.Data(i,3) = {datestr(start)};
                app.UITable_3.Data(i,4) = {datestr(finish)};
                app.UITable_3.Data(i,5) = {int2str(P)};
                
                app.UITable_4.Data(i,1) = {int2str(BusArray(i).ID)};
                app.UITable_4.Data(i,2) = {datestr(BusArray(i).Arrival_time)};
                app.UITable_4.Data(i,3) = {datestr(start)};
                app.UITable_4.Data(i,4) = {datestr(finish)};
                app.UITable_4.Data(i,5) = {int2str(P)};
       
            end


            Pmax_soll = str2num(app.MaxPowerkWEditField.Value);
            if (Pmax_soll  ~= 0)
                if abs(Pmax_ist) < abs(Pmax_soll*10^3)
                    f = msgbox('Operation Completed Max Power was not exceeded !','Success');
                else
                    f = msgbox('Operation Completed Max Power was  exceeded !','Failed');
                end
            end
            
            Nmax_soll = str2num(app.MaxChargersEditField.Value);
            if (Nmax_soll ~= 0)
                if Nmax_ist <= Nmax_soll
                    f = msgbox('Operation Completed Max number of Chargers was not exceeded !','Success');
                else
                    f = msgbox('Operation Completed Max number of Chargers was exceeded !','Failed');
                end
            end
            app.Sched_Bus_Array = BusArray;
        end

        % Selection changed function: GoalButtonGroup_2
        function goal_change2(app, event)
            app.MinPowerButton.Value = app.MinPowerButton_2.Value;
            selectedButton = app.GoalButtonGroup_2.SelectedObject
            if strcmp(selectedButton.Text,'Min Power');
                app.Opt_Goal = 'Pmin';
                disp('pMin')
            else
                app.Opt_Goal = 'Nmin';
                disp('nMin')
            end
            
        end

        % Selection changed function: GoalButtonGroup
        function goal_change(app, event)
            app.MinPowerButton_2.Value = app.MinPowerButton.Value;
            selectedButton = app.GoalButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Min Power');
                   app.Opt_Goal = 'Pmin';
                   disp('pMin')
               else
                  app.Opt_Goal = 'Nmin';
                  disp('nMin')
            end
        end

        % Selection changed function: VariationParameterButtonGroup
        function variation(app, event)
            app.TimeButton_2.Value =app.TimeButton.Value;
            selectedButton = app.VariationParameterButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Time');
                app.Var = 'T';
                disp('T')
            else
                app.Var = 'P';
                disp('P')
            end
            
        end

        % Selection changed function: 
        % VariationParameterButtonGroup_2
        function variation2(app, event)
            app.TimeButton.Value =app.TimeButton_2.Value;
            selectedButton = app.VariationParameterButtonGroup_2.SelectedObject;
            if strcmp(selectedButton.Text,'Time');
                app.Var = 'T';
                disp('T')
            else
                app.Var = 'P';
                disp('P')
            end
            
        end

        % Button pushed function: ScheduleButton_2
        function Sched2(app, event)
            arrtime=datetime('00:00','InputFormat','HH:mm');
            deptime=datetime('06:00','InputFormat','HH:mm');
            global dt iteration 
            iteration = 0;
            dt =  fix(app.StepsSlider.Value) ;
       
             BusArray =app.Sched_Bus_Array;
             [~,sz] =size(BusArray);
            for i=1:sz
                BusArray(i).CalcP(dt,BusArray(i).Pmax,0,'s');
                arrBus = datetime(BusArray(i).Arrival_time, 'InputFormat','HH:mm');
                depBus = datetime(BusArray(i).Departure_time, 'InputFormat','HH:mm');
                start_time=abs(etime(datevec(arrtime),datevec(arrBus)));
                BusArray(i).ChargingStart = start_time;
                BusArray(i).Arrival_seconds = start_time;                
                all_time = abs(etime(datevec(arrBus),datevec(depBus)));
                all_time = round(all_time);
                LookUp = app.VoltSoc_LookUp;
                [Energie_stored, Energie_grid] = CalC_Energie(BusArray(i),BusArray(i).Peff,LookUp);
                Pm = (Energie_grid*1000*3600)/(all_time) ;
                BusArray(i).Pmin=Pm;               
            end


            if strcmp(app.ExternPlotSwitch.Value,'Off')
                withplot = 0;
            else
                withplot = 1;
            end
             
            
            tic;
       
            
            if strcmp(app.Opt_Goal,'Pmin') && strcmp(app.Var,'T')
                disp('minimizing P with t')
                [Pmax_ist, Nmax_ist] = minimize_total_Power_with_t0(app.Sched_Bus_Array, str2num(app.MaxPowerkWEditField_2.Value),arrtime,deptime, withplot);
            elseif strcmp(app.Opt_Goal,'Nmin') && strcmp(app.Var,'T')
                disp('minimizing N with t')
                [Pmax_ist, Nmax_ist] = minimize_nChargers_with_t0(app.Sched_Bus_Array,str2num(app.MaxChargersEditField_2.Value),arrtime,deptime, withplot);
            elseif strcmp(app.Opt_Goal,'Pmin') && strcmp(app.Var,'P')
                disp('minimizing P with P')
                [Pmax_ist, Nmax_ist] = minimize_total_Power_with_P(app.Sched_Bus_Array,str2num(app.MaxPowerkWEditField_2.Value),arrtime,deptime, withplot);
            else
                f = msgbox('Reducing Number Of Chargers with P variation not possible !','Error');
            end
            toc
            

            for i=1:sz
                start = seconds(BusArray(i).ChargingStart);
                start.Format = 'hh:mm';
                finish = seconds(BusArray(i).ChargingStart+BusArray(i).ChargingTime);
                finish.Format ='hh:mm';
                P = BusArray(i).Pmax/1000 ;
                app.UITable_3.Data(i,1) = {int2str(BusArray(i).ID)};
                app.UITable_3.Data(i,2) = {datestr(BusArray(i).Arrival_time)};
                app.UITable_3.Data(i,3) = {datestr(start)};
                app.UITable_3.Data(i,4) = {datestr(finish)};
                app.UITable_3.Data(i,5) = {int2str(P)};
              
                app.UITable_4.Data(i,1) = {int2str(BusArray(i).ID)};
                app.UITable_4.Data(i,2) = {datestr(BusArray(i).Arrival_time)};
                app.UITable_4.Data(i,3) = {datestr(start)};
                app.UITable_4.Data(i,4) = {datestr(finish)};
                app.UITable_4.Data(i,5) = {int2str(P)};
       
            end
            
            Pmax_soll = str2num(app.MaxPowerkWEditField_2.Value);
            if (Pmax_soll  ~= 0)
                if abs(Pmax_ist) < abs(Pmax_soll*10^3)
                    f = msgbox('Operation Completed Max Power was not exceeded !','Success');
                else
                    f = msgbox('Operation Completed Max Power was  exceeded !','Failed');
                end
            end
                         
            Nmax_soll = str2num(app.MaxChargersEditField_2.Value);
            if (Nmax_soll ~= 0)
                if Nmax_ist <= Nmax_soll
                    f = msgbox('Operation Completed Max number of Chargers was not exceeded !','Success');
                else
                    f = msgbox('Operation Completed Max number of Chargers was exceeded !','Failed');
                end
            end
        end

        % Button pushed function: SaveDataButton, SaveDataButton_2
        function Save(app, event)
            savedData = app.UITable_3.Data;
            T = cell2table(savedData(1:end,:),'VariableNames',{'ID','Arrival','Charging_Start','Charging_Finish','Charging_Power_kW'})
            [FileName,PathName] = uiputfile(['mytable.','csv'],'Save table data');
            writetable(T,[PathName,FileName]) 
            BusArray = app.Sched_Bus_Array;
            [FileName,PathName]
            [~,nr_bus] =size(BusArray);
            FileName = strcat('BusList',int2str(nr_bus),'.mat');
            save([PathName,FileName], 'BusArray');
        end

        % Button pushed function: OKButton_2
        function Insert_Data(app, event)
            [~,sz] =size(app.Sched_Bus_Array);
            app.UITable_3.Data =[];
            app.UITable_4.Data =[];
            for i =1:sz
                start = seconds(app.Sched_Bus_Array(i).ChargingStart);
                start.Format = 'hh:mm';
                finish = seconds(app.Sched_Bus_Array(i).ChargingStart+app.Sched_Bus_Array(i).ChargingTime);
                finish.Format ='hh:mm';
                str1 = app.Sched_Bus_Array(i).ID;
                str2 = app.Sched_Bus_Array(i).Arrival_time;
                str3 = datestr(start);
                str4 = datestr(finish);
                str5 = int2str(app.Sched_Bus_Array(i).Pmax/1000);
                app.UITable_3.Data  = [app.UITable_3.Data;{str1,str2,str3,str4,str5}]; 
                app.UITable_4.Data  = [app.UITable_4.Data;{str1,str2,str3,str4,str5}]; 
            end
            
      
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EBusUIFigure = uifigure('Visible', 'off');
            app.EBusUIFigure.Name = 'E Bus';
            app.TabGroup = uitabgroup(app.EBusUIFigure);
            app.SchedulerTab = uitab(app.TabGroup);
            app.SchedulerTab.Title = 'Scheduler';
            app.TabGroup2 = uitabgroup(app.SchedulerTab);
            app.EnterManuallyTab = uitab(app.TabGroup2);
            app.EnterManuallyTab.Title = 'Enter Manually';
            app.BusParametersPanel = uipanel(app.EnterManuallyTab);
            app.BusParametersPanel.Title = 'Bus Parameters';
            app.IDEditFieldLabel = uilabel(app.BusParametersPanel);
            app.IDEditFieldLabel.Text = 'ID';
            app.IDEditField = uieditfield(app.BusParametersPanel, 'numeric');
            app.IDEditField.ValueChangedFcn = createCallbackFcn(app, @Set_BusID, true);
            app.IDEditField.Value = 1;
            app.ChargerEfficiencyEditFieldLabel = uilabel(app.BusParametersPanel);
            app.ChargerEfficiencyEditFieldLabel.Text = 'Charger Efficiency[%]';
            app.ChargerEfficiencyEditField = uieditfield(app.BusParametersPanel, 'numeric');
            app.ChargerEfficiencyEditField.Limits = [10 100];
            app.ChargerEfficiencyEditField.ValueChangedFcn = createCallbackFcn(app, @Set_BusChargerPeff, true);
            app.ChargerEfficiencyEditField.Value = 98;
            app.ChargerPowerkWEditField_2Label = uilabel(app.BusParametersPanel);
            app.ChargerPowerkWEditField_2Label.Text = 'Charger Power[kW]';
            app.ChargerPowerkWEditField_2 = uieditfield(app.BusParametersPanel, 'numeric');
            app.ChargerPowerkWEditField_2.Limits = [1 500];
            app.ChargerPowerkWEditField_2.ValueChangedFcn = createCallbackFcn(app, @Set_BusChargerP, true);
            app.ChargerPowerkWEditField_2.Value = 25;
            app.AddBusButton = uibutton(app.BusParametersPanel, 'push');
            app.AddBusButton.ButtonPushedFcn = createCallbackFcn(app, @Add_Bus, true);
            app.AddBusButton.Text = 'Add Bus';
            app.Arrival_timeEditField_2Label = uilabel(app.BusParametersPanel);
            app.Arrival_timeEditField_2Label.Text = 'Arrival_time';
            app.Arrival_timeEditField_2 = uieditfield(app.BusParametersPanel, 'text');
            app.Arrival_timeEditField_2.ValueChangedFcn = createCallbackFcn(app, @Set_BusArrTime, true);
            app.Arrival_timeEditField_2.Value = '00:30';
            app.Depart_timeEditFieldLabel = uilabel(app.BusParametersPanel);
            app.Depart_timeEditFieldLabel.Text = 'Depart_time';
            app.Depart_timeEditField = uieditfield(app.BusParametersPanel, 'text');
            app.Depart_timeEditField.ValueChangedFcn = createCallbackFcn(app, @Set_BusDepTime, true);
            app.Depart_timeEditField.Value = '05:30';
            app.RemoveLastButton = uibutton(app.BusParametersPanel, 'push');
            app.RemoveLastButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveLast, true);
            app.RemoveLastButton.Text = 'RemoveLast';
            app.SchedulingParametersPanel = uipanel(app.EnterManuallyTab);
            app.SchedulingParametersPanel.Title = 'Scheduling Parameters';
            app.StepsSliderLabel = uilabel(app.SchedulingParametersPanel);
            app.StepsSliderLabel.Text = 'Step(s)';
            app.StepsSlider = uislider(app.SchedulingParametersPanel);
            app.StepsSlider.Limits = [50 500];
            app.StepsSlider.ValueChangedFcn = createCallbackFcn(app, @Set_SchedStep, true);
            app.StepsSlider.ValueChangingFcn = createCallbackFcn(app, @Set_SchedStepLabel, true);
            app.StepsSlider.Value = 250;
            app.ScheduleButton = uibutton(app.SchedulingParametersPanel, 'push');
            app.ScheduleButton.ButtonPushedFcn = createCallbackFcn(app, @Schedule, true);
            app.ScheduleButton.Text = 'Schedule';
            app.Label_2 = uilabel(app.SchedulingParametersPanel);
            app.Label_2.Text = '0';
            app.GoalButtonGroup = uibuttongroup(app.SchedulingParametersPanel);
            app.GoalButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @goal_change, true);
            app.GoalButtonGroup.Title = 'Goal';
            app.MinPowerButton = uiradiobutton(app.GoalButtonGroup);
            app.MinPowerButton.Text = 'Min Power';
            app.MinPowerButton.Value = true;
            app.MinChargersButton = uiradiobutton(app.GoalButtonGroup);
            app.MinChargersButton.Text = 'Min Chargers';
            app.VariationParameterButtonGroup = uibuttongroup(app.SchedulingParametersPanel);
            app.VariationParameterButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @variation, true);
            app.VariationParameterButtonGroup.Title = 'Variation Parameter';
            app.TimeButton = uiradiobutton(app.VariationParameterButtonGroup);
            app.TimeButton.Text = 'Time';
            app.TimeButton.Value = true;
            app.PowerButton = uiradiobutton(app.VariationParameterButtonGroup);
            app.PowerButton.Text = 'Power';
            app.MaxPowerkWEditFieldLabel = uilabel(app.SchedulingParametersPanel);
            app.MaxPowerkWEditFieldLabel.Text = 'Max Power [kW]';
            app.MaxPowerkWEditField = uieditfield(app.SchedulingParametersPanel, 'text');
            app.MaxPowerkWEditField.Value = '0';
            app.MaxChargersEditFieldLabel = uilabel(app.SchedulingParametersPanel);
            app.MaxChargersEditFieldLabel.Text = 'Max Chargers';
            app.MaxChargersEditField = uieditfield(app.SchedulingParametersPanel, 'text');
            app.MaxChargersEditField.Value = '0';
            app.UITable_3 = uitable(app.EnterManuallyTab);
            app.UITable_3.ColumnName = {'Bus ID'; 'Arrival'; 'Start Charging'; 'Finish Charging'; 'Power'};
            app.UITable_3.RowName = {};
            app.BatteryParametersPanel_2 = uipanel(app.EnterManuallyTab);
            app.BatteryParametersPanel_2.Title = 'Battery Parameters';
            app.CapacitykAhEditField_2Label = uilabel(app.BatteryParametersPanel_2);
            app.CapacitykAhEditField_2Label.Text = 'Capacity [kAh]';
            app.CapacitykAhEditField_2 = uieditfield(app.BatteryParametersPanel_2, 'numeric');
            app.CapacitykAhEditField_2.Limits = [0 Inf];
            app.CapacitykAhEditField_2.ValueChangedFcn = createCallbackFcn(app, @Set_BusCapacity, true);
            app.CapacitykAhEditField_2.Value = 50;
            app.TemperatureCEditField_2Label = uilabel(app.BatteryParametersPanel_2);
            app.TemperatureCEditField_2Label.Text = 'Temperature [°C]';
            app.TemperatureCEditField_2 = uieditfield(app.BatteryParametersPanel_2, 'numeric');
            app.TemperatureCEditField_2.Limits = [-20 50];
            app.TemperatureCEditField_2.ValueChangedFcn = createCallbackFcn(app, @Set_BusTemp, true);
            app.TemperatureCEditField_2.Value = 25;
            app.NrOfSerieCellsEditField_2Label = uilabel(app.BatteryParametersPanel_2);
            app.NrOfSerieCellsEditField_2Label.Text = 'Nr Of Serie Cells';
            app.NrOfSerieCellsEditField_2 = uieditfield(app.BatteryParametersPanel_2, 'numeric');
            app.NrOfSerieCellsEditField_2.Limits = [1 300];
            app.NrOfSerieCellsEditField_2.ValueChangedFcn = createCallbackFcn(app, @Set_BusSerCells, true);
            app.NrOfSerieCellsEditField_2.Value = 120;
            app.CellTypeDropDown_2Label = uilabel(app.BatteryParametersPanel_2);
            app.CellTypeDropDown_2Label.Text = 'Cell Type';
            app.CellTypeDropDown_2 = uidropdown(app.BatteryParametersPanel_2);
            app.CellTypeDropDown_2.Items = {'LiPo', 'LTO', 'NMC'};
            app.CellTypeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @Set_BusCellType, true);
            app.CellTypeDropDown_2.Value = 'LiPo';
            app.NrOfParallelCellsEditField_2Label = uilabel(app.BatteryParametersPanel_2);
            app.NrOfParallelCellsEditField_2Label.Text = 'Nr Of Parallel Cells';
            app.NrOfParallelCellsEditField_2 = uieditfield(app.BatteryParametersPanel_2, 'numeric');
            app.NrOfParallelCellsEditField_2.Limits = [1 50];
            app.NrOfParallelCellsEditField_2.ValueChangedFcn = createCallbackFcn(app, @Set_BusPllCells, true);
            app.NrOfParallelCellsEditField_2.Value = 10;
            app.ArrivalSOCEditField_2Label = uilabel(app.BatteryParametersPanel_2);
            app.ArrivalSOCEditField_2Label.Text = 'Arrival SOC [%]';
            app.ArrivalSOCEditField_2 = uieditfield(app.BatteryParametersPanel_2, 'numeric');
            app.ArrivalSOCEditField_2.Limits = [10 80];
            app.ArrivalSOCEditField_2.ValueChangedFcn = createCallbackFcn(app, @Set_BusArrSOC, true);
            app.ArrivalSOCEditField_2.Value = 20;
            app.DepartureSOCEditField_2Label = uilabel(app.BatteryParametersPanel_2);
            app.DepartureSOCEditField_2Label.Text = 'Departure SOC[%]';
            app.DepartureSOCEditField_2 = uieditfield(app.BatteryParametersPanel_2, 'numeric');
            app.DepartureSOCEditField_2.Limits = [20 90];
            app.DepartureSOCEditField_2.ValueChangedFcn = createCallbackFcn(app, @Set_BusDepSOC, true);
            app.DepartureSOCEditField_2.Value = 80;
            app.SaveDataButton_2 = uibutton(app.EnterManuallyTab, 'push');
            app.SaveDataButton_2.ButtonPushedFcn = createCallbackFcn(app, @Save, true);
            app.SaveDataButton_2.Text = 'Save Data';
            app.ImportTab = uitab(app.TabGroup2);
            app.ImportTab.Title = 'Import';
            app.ImportBusListPanel = uipanel(app.ImportTab);
            app.ImportBusListPanel.Title = 'Import Bus List';
            app.OKButton_2 = uibutton(app.ImportBusListPanel, 'push');
            app.OKButton_2.ButtonPushedFcn = createCallbackFcn(app, @Insert_Data, true);
            app.OKButton_2.Text = 'OK';
            app.LoadmatfileButton = uibutton(app.ImportBusListPanel, 'push');
            app.LoadmatfileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenCSV, true);
            app.LoadmatfileButton.Text = 'Load .mat file';
            app.UITable_4 = uitable(app.ImportTab);
            app.UITable_4.ColumnName = {'Bus ID'; 'Arrival'; 'Start Charging'; 'Finish Charging'; 'Power'};
            app.UITable_4.RowName = {};
            app.SchedulingParametersPanel_2 = uipanel(app.ImportTab);
            app.SchedulingParametersPanel_2.Title = 'Scheduling Parameters';
            app.StepsSlider_2Label = uilabel(app.SchedulingParametersPanel_2);
            app.StepsSlider_2Label.Text = 'Step(s)';
            app.StepsSlider_2 = uislider(app.SchedulingParametersPanel_2);
            app.StepsSlider_2.Limits = [50 500];
            app.StepsSlider_2.ValueChangedFcn = createCallbackFcn(app, @Set_SchedStep, true);
            app.StepsSlider_2.ValueChangingFcn = createCallbackFcn(app, @Set_SchedStepLabel, true);
            app.StepsSlider_2.Value = 250;
            app.ScheduleButton_2 = uibutton(app.SchedulingParametersPanel_2, 'push');
            app.ScheduleButton_2.ButtonPushedFcn = createCallbackFcn(app, @Sched2, true);
            app.ScheduleButton_2.Text = 'Schedule';
            app.Label_3 = uilabel(app.SchedulingParametersPanel_2);
            app.Label_3.Text = '0';
            app.GoalButtonGroup_2 = uibuttongroup(app.SchedulingParametersPanel_2);
            app.GoalButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @goal_change2, true);
            app.GoalButtonGroup_2.Title = 'Goal';
            app.MinPowerButton_2 = uiradiobutton(app.GoalButtonGroup_2);
            app.MinPowerButton_2.Text = 'Min Power';
            app.MinPowerButton_2.Value = true;
            app.MinChargersButton_2 = uiradiobutton(app.GoalButtonGroup_2);
            app.MinChargersButton_2.Text = 'Min Chargers';
            app.VariationParameterButtonGroup_2 = uibuttongroup(app.SchedulingParametersPanel_2);
            app.VariationParameterButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @variation2, true);
            app.VariationParameterButtonGroup_2.Title = 'Variation Parameter';
            app.TimeButton_2 = uiradiobutton(app.VariationParameterButtonGroup_2);
            app.TimeButton_2.Text = 'Time';
            app.TimeButton_2.Value = true;
            app.PowerButton_2 = uiradiobutton(app.VariationParameterButtonGroup_2);
            app.PowerButton_2.Text = 'Power';
            app.MaxPowerkWEditField_2Label = uilabel(app.SchedulingParametersPanel_2);
            app.MaxPowerkWEditField_2Label.Text = 'Max Power [kW]';
            app.MaxPowerkWEditField_2 = uieditfield(app.SchedulingParametersPanel_2, 'text');
            app.MaxPowerkWEditField_2.Value = '0';
            app.MaxChargersEditField_2Label = uilabel(app.SchedulingParametersPanel_2);
            app.MaxChargersEditField_2Label.Text = 'Max Chargers';
            app.MaxChargersEditField_2 = uieditfield(app.SchedulingParametersPanel_2, 'text');
            app.MaxChargersEditField_2.Value = '0';
            app.SaveDataButton = uibutton(app.ImportTab, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @Save, true);
            app.SaveDataButton.Text = 'Save Data';
            app.SimulatorTab = uitab(app.TabGroup);
            app.SimulatorTab.Title = 'Simulator';
            app.BatteryParametersPanel = uipanel(app.SimulatorTab);
            app.BatteryParametersPanel.Title = 'Battery Parameters';
            app.CapacitykAhEditFieldLabel = uilabel(app.BatteryParametersPanel);
            app.CapacitykAhEditFieldLabel.Text = 'Capacity [kAh]';
            app.CapacitykAhEditField = uieditfield(app.BatteryParametersPanel, 'numeric');
            app.CapacitykAhEditField.ValueChangedFcn = createCallbackFcn(app, @Set_SimCapacity, true);
            app.CapacitykAhEditField.Value = 45;
            app.TemperatureCEditFieldLabel = uilabel(app.BatteryParametersPanel);
            app.TemperatureCEditFieldLabel.Text = 'Temperature [°C]';
            app.TemperatureCEditField = uieditfield(app.BatteryParametersPanel, 'numeric');
            app.TemperatureCEditField.Limits = [-20 50];
            app.TemperatureCEditField.ValueChangedFcn = createCallbackFcn(app, @Set_SimTemperature, true);
            app.TemperatureCEditField.Value = 25;
            app.NrOfParallelCellsEditFieldLabel = uilabel(app.BatteryParametersPanel);
            app.NrOfParallelCellsEditFieldLabel.Text = 'Nr Of Parallel Cells';
            app.NrOfParallelCellsEditField = uieditfield(app.BatteryParametersPanel, 'numeric');
            app.NrOfParallelCellsEditField.Limits = [1 50];
            app.NrOfParallelCellsEditField.ValueChangedFcn = createCallbackFcn(app, @Set_SimNrPllCells, true);
            app.NrOfParallelCellsEditField.Value = 10;
            app.NrOfSerieCellsEditFieldLabel = uilabel(app.BatteryParametersPanel);
            app.NrOfSerieCellsEditFieldLabel.Text = 'Nr Of Serie Cells';
            app.NrOfSerieCellsEditField = uieditfield(app.BatteryParametersPanel, 'numeric');
            app.NrOfSerieCellsEditField.Limits = [1 300];
            app.NrOfSerieCellsEditField.ValueChangedFcn = createCallbackFcn(app, @Set_SimNrSerCells, true);
            app.NrOfSerieCellsEditField.Value = 100;
            app.CellTypeDropDownLabel = uilabel(app.BatteryParametersPanel);
            app.CellTypeDropDownLabel.Text = 'Cell Type';
            app.CellTypeDropDown = uidropdown(app.BatteryParametersPanel);
            app.CellTypeDropDown.Items = {'LiPo', 'LTO', 'NMC'};
            app.CellTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @Set_SimCellType, true);
            app.CellTypeDropDown.Value = 'LiPo';
            app.ArrivalSOCEditFieldLabel = uilabel(app.BatteryParametersPanel);
            app.ArrivalSOCEditFieldLabel.Text = 'Arrival SOC [%]';
            app.ArrivalSOCEditField = uieditfield(app.BatteryParametersPanel, 'numeric');
            app.ArrivalSOCEditField.Limits = [10 80];
            app.ArrivalSOCEditField.ValueChangedFcn = createCallbackFcn(app, @Set_SimStartSOC, true);
            app.ArrivalSOCEditField.Value = 10;
            app.DepartureSOCEditFieldLabel = uilabel(app.BatteryParametersPanel);
            app.DepartureSOCEditFieldLabel.Text = 'Departure SOC[%]';
            app.DepartureSOCEditField = uieditfield(app.BatteryParametersPanel, 'numeric');
            app.DepartureSOCEditField.Limits = [20 90];
            app.DepartureSOCEditField.ValueChangedFcn = createCallbackFcn(app, @Set_SimEndSOC, true);
            app.DepartureSOCEditField.Value = 90;
            app.UIAxes = uiaxes(app.SimulatorTab);
            title(app.UIAxes, 'Power')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Power')
            app.SimulationParametersPanel = uipanel(app.SimulatorTab);
            app.SimulationParametersPanel.Title = 'Simulation Parameters';
            app.StepSliderLabel = uilabel(app.SimulationParametersPanel);
            app.StepSliderLabel.Text = 'Step';
            app.StepSlider = uislider(app.SimulationParametersPanel);
            app.StepSlider.Limits = [10 100];
            app.StepSlider.ValueChangedFcn = createCallbackFcn(app, @Set_SimStep, true);
            app.StepSlider.ValueChangingFcn = createCallbackFcn(app, @Set_SimStepLabel, true);
            app.StepSlider.Value = 30;
            app.UnitDropDownLabel = uilabel(app.SimulationParametersPanel);
            app.UnitDropDownLabel.Text = 'Unit';
            app.UnitDropDown = uidropdown(app.SimulationParametersPanel);
            app.UnitDropDown.Items = {'Hours', 'Minutes', 'Seconds'};
            app.UnitDropDown.ValueChangedFcn = createCallbackFcn(app, @Set_SimUnit, true);
            app.UnitDropDown.Value = 'Seconds';
            app.RunSimulationButton = uibutton(app.SimulationParametersPanel, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @Run_Simulation, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.ChargerPowerkWEditFieldLabel = uilabel(app.SimulationParametersPanel);
            app.ChargerPowerkWEditFieldLabel.Text = 'Charger Power [kW]';
            app.ChargerPowerkWEditField = uieditfield(app.SimulationParametersPanel, 'numeric');
            app.ChargerPowerkWEditField.Limits = [1 500];
            app.ChargerPowerkWEditField.ValueChangedFcn = createCallbackFcn(app, @Set_SimP, true);
            app.ChargerPowerkWEditField.Value = 45;
            app.EfficiencyLabel = uilabel(app.SimulationParametersPanel);
            app.EfficiencyLabel.Text = 'Efficiency[%]';
            app.EfficiencyEditField = uieditfield(app.SimulationParametersPanel, 'numeric');
            app.EfficiencyEditField.Limits = [10 100];
            app.EfficiencyEditField.ValueChangedFcn = createCallbackFcn(app, @Set_SimPeff, true);
            app.EfficiencyEditField.Value = 96;
            app.Label = uilabel(app.SimulatorTab);
            app.Label.Text = '0';
            app.ExternPlotSwitchLabel = uilabel(app.EBusUIFigure);
            app.ExternPlotSwitchLabel.Text = 'Extern Plot';
            app.ExternPlotSwitch = uiswitch(app.EBusUIFigure, 'slider');
            app.ExternPlotSwitch.ValueChangedFcn = createCallbackFcn(app, @Set_ExternPlot, true);
            app.ExternPlotSwitch.Value = 'On';
            % Show the figure after all components are created
            app.EBusUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EBusUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EBusUIFigure)
        end
    end
end